classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RemoveBButton      matlab.ui.control.Button
        RemoveGButton      matlab.ui.control.Button
        RemoveRButton      matlab.ui.control.Button
        UmbralSlider       matlab.ui.control.Slider
        UmbralSliderLabel  matlab.ui.control.Label
        SAVEButton         matlab.ui.control.Button
        ToGrayButton       matlab.ui.control.Button
        CLOSEButton        matlab.ui.control.Button
        ADDButton          matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagen;
        imagenProcesada;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            
        % Lee una imagen
        img = uigetfile({'*.jpg;*.png'});
        app.imagen = imread(img);

        % Mostrar la imagen en el UI
        axis(app.UIAxes, 'tight');
        imshow(app.imagen,'Parent', app.UIAxes);

        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            % cerrar programa
            app.delete;
        end

        % Button pushed function: ToGrayButton
        function ToGrayButtonPushed(app, event)

            % Convertir imagen a grises
            app.imagenProcesada = im2gray(app.imagen);

            %mostrar en axis 2
            axis(app.UIAxes, 'tight');
            imshow(app.imagenProcesada, 'Parent', app.UIAxes2);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            
            % Guardar la imagen procesada
            imwrite(app.imagenProcesada,'img_preocesada.png');
        end

        % Value changed function: UmbralSlider
        function UmbralSliderValueChanged(app, event)
            % valor del umbral
            value = app.UmbralSlider.Value;

            % Conversion a blanco y negro
            imagenGris = im2gray(app.imagen)
            app.imagenProcesada = im2bw(imagenGris,value);
            
            %mostrar en axis 2
            axis(app.UIAxes, 'tight');
            imshow(app.imagenProcesada, 'Parent', app.UIAxes2);

            % Guardar la imagen procesada
            imwrite(app.imagenProcesada,'img_preocesada.png');
        end

        % Button pushed function: RemoveRButton
        function RemoveRButtonPushed(app, event)
            %% Lectura de pixeles
     
            app.imagen(:,:,1) = 0;

            app.imagenProcesada = app.imagen; %lectura de todo el canal R

            %mostrar en axis 2
            axis(app.UIAxes, 'tight');
            imshow(app.imagenProcesada, 'Parent', app.UIAxes2);

            % Guardar la imagen procesada
            imwrite(app.imagenProcesada,'img_preocesada.png');
        end

        % Button pushed function: RemoveGButton
        function RemoveGButtonPushed(app, event)
            app.imagen(:,:,2) = 0;

            app.imagenProcesada = app.imagen; %lectura de todo el canal R

            %mostrar en axis 2
            axis(app.UIAxes, 'tight');
            imshow(app.imagenProcesada, 'Parent', app.UIAxes2);

            % Guardar la imagen procesada
            imwrite(app.imagenProcesada,'img_preocesada.png');
        end

        % Button pushed function: RemoveBButton
        function RemoveBButtonPushed(app, event)
            app.imagen(:,:,3) = 0;

            app.imagenProcesada = app.imagen; %lectura de todo el canal R

            %mostrar en axis 2
            axis(app.UIAxes, 'tight');
            imshow(app.imagenProcesada, 'Parent', app.UIAxes2);

            % Guardar la imagen procesada
            imwrite(app.imagenProcesada,'img_preocesada.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.ToGrayButton = uibutton(app.UIFigure, 'push');
            app.ToGrayButton.ButtonPushedFcn = createCallbackFcn(app, @ToGrayButtonPushed, true);
            app.ToGrayButton.Text = 'To Gray';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.UmbralSliderLabel = uilabel(app.UIFigure);
            app.UmbralSliderLabel.Text = 'Umbral';
            app.UmbralSlider = uislider(app.UIFigure);
            app.UmbralSlider.Limits = [0 1];
            app.UmbralSlider.ValueChangedFcn = createCallbackFcn(app, @UmbralSliderValueChanged, true);
            app.RemoveRButton = uibutton(app.UIFigure, 'push');
            app.RemoveRButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRButtonPushed, true);
            app.RemoveRButton.Text = 'Remove R';
            app.RemoveGButton = uibutton(app.UIFigure, 'push');
            app.RemoveGButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGButtonPushed, true);
            app.RemoveGButton.Text = 'Remove G';
            app.RemoveBButton = uibutton(app.UIFigure, 'push');
            app.RemoveBButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBButtonPushed, true);
            app.RemoveBButton.Text = 'Remove B';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end